classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ImageGold            matlab.ui.control.Image
        ImageLeprechaun      matlab.ui.control.Image
        LabelPercentValue    matlab.ui.control.Label
        LabelSuccessValue    matlab.ui.control.Label
        LabelAttemptsValue   matlab.ui.control.Label
        PercentCorrectLabel  matlab.ui.control.Label
        SuccessLabel         matlab.ui.control.Label
        AttemptsLabel        matlab.ui.control.Label
        Button_3             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_1             matlab.ui.control.Button
    end

    
    properties (Access = private)
        LeprechaunPositions
        GoldPositions
        LeprechaunImageSources
        Attempts = 0
        Successes = 0
    end
    
    methods (Access = private)
        
        function makeAGuess(app, leprechaunIndex)
            app.Attempts = app.Attempts + 1;

            app.ImageLeprechaun.Position = app.LeprechaunPositions(leprechaunIndex,:);
            goldIndex = randi([1,3]);
            app.ImageGold.Position = app.GoldPositions(goldIndex, :);

            if goldIndex == leprechaunIndex
                app.ImageLeprechaun.ImageSource = app.LeprechaunImageSources(1);
                app.Successes = app.Successes + 1;
            else
                app.ImageLeprechaun.ImageSource = app.LeprechaunImageSources(2);
            end

            app.LabelAttemptsValue.Text = string(app.Attempts);
            app.LabelSuccessValue.Text = string(app.Successes);
            app.LabelPercentValue.Text = string(app.Successes / app.Attempts * 100);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.LeprechaunImageSources = ["./leprechaunHappy.png", "./leprechaunSad.png"];

            app.LeprechaunPositions(1,:) = [50,248,100,100];
            app.LeprechaunPositions(2,:) = [267,248,100,100];
            app.LeprechaunPositions(3,:) = [484,248,100,100];

            app.GoldPositions(1,:) = [50,24,100,100];
            app.GoldPositions(2,:) = [267,24,100,100];
            app.GoldPositions(3,:) = [484,24,100,100];

        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            % app.ImageLeprechaun.ImageSource = app.LeprechaunImageSources(1);
            % app.ImageLeprechaun.Position = app.LeprechaunPositions(1,:);
            app.makeAGuess(1);

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % app.ImageLeprechaun.ImageSource = app.LeprechaunImageSources(1);
            % app.ImageLeprechaun.Position = app.LeprechaunPositions(2,:);
            app.makeAGuess(2);
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % app.ImageLeprechaun.ImageSource = app.LeprechaunImageSources(2);
            % app.ImageLeprechaun.Position = app.LeprechaunPositions(3,:);
            app.makeAGuess(3);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.AttemptsLabel = uilabel(app.UIFigure);
            app.AttemptsLabel.Text = 'Attempts:';
            app.SuccessLabel = uilabel(app.UIFigure);
            app.SuccessLabel.Text = 'Success:';
            app.PercentCorrectLabel = uilabel(app.UIFigure);
            app.PercentCorrectLabel.Text = 'Percent Correct:';
            app.LabelAttemptsValue = uilabel(app.UIFigure);
            app.LabelAttemptsValue.Text = '0';
            app.LabelSuccessValue = uilabel(app.UIFigure);
            app.LabelSuccessValue.Text = '0';
            app.LabelPercentValue = uilabel(app.UIFigure);
            app.LabelPercentValue.Text = '%';
            app.ImageLeprechaun = uiimage(app.UIFigure);
            app.ImageLeprechaun.ImageSource = fullfile(pathToMLAPP, 'leprechaunSad.png');
            app.ImageGold = uiimage(app.UIFigure);
            app.ImageGold.ImageSource = fullfile(pathToMLAPP, 'gold.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end