classdef hello < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MiscPanel                 matlab.ui.container.Panel
        beepButton                matlab.ui.control.Button
        ModesPanel                matlab.ui.container.Panel
        DriveuntillineButton      matlab.ui.control.Button
        StoplinefollowingButton   matlab.ui.control.Button
        StartlinefollowingButton  matlab.ui.control.Button
        DriveuntilwallButton      matlab.ui.control.Button
        StreamingPanel            matlab.ui.container.Panel
        TimeTimestampLabel        matlab.ui.control.Label
        RightLamp                 matlab.ui.control.Lamp
        RightLampLabel            matlab.ui.control.Label
        MiddleLamp                matlab.ui.control.Lamp
        MiddleLampLabel           matlab.ui.control.Label
        LeftLamp                  matlab.ui.control.Lamp
        LeftLampLabel             matlab.ui.control.Label
        StreamingSwitch           matlab.ui.control.Switch
        StreamingSwitchLabel      matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        SpeedSlider               matlab.ui.control.Slider
        SpeedSliderLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        priorKey = ""
    end

    methods (Access = private)

        function color = getLampColor(app, letter)
            if letter == "B"
                color = 'red';
            else
                color = 'green';
            end

        end

        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            if strcmp(messageType, "set_speeds")
                messagePayload = messageStruct.payload;
            end
            % 
            if strcmp(messageType, "line_sensor_reading")

                messagePayload = messageStruct.payload;
                app.TimeTimestampLabel.Text = messagePayload.time + "";


                app.LeftLamp.Color = app.getLampColor(messagePayload.left);
                app.MiddleLamp.Color = app.getLampColor(messagePayload.middle);
                app.RightLamp.Color = app.getLampColor(messagePayload.right);

            end

        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStruct);
            write(app.mqClient, "me435/tsurutn/to_pi", messageJson);
            fprintf("Sent: %s\n", messageJson);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/tsurutn/#", "Callback", @app.myCallback);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, app.priorKey)
                return; % The key is being held down ignore thi sone
            end
            app.priorKey = key;

            speed = round(app.SpeedSlider.Value);

            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds", [speed speed speed speed])
            end

            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds", [-speed -speed -speed -speed])
            end

            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds", [-speed speed speed -speed]) % not sure
            end

            if strcmp(key, "rightarrow")
                app.sendMessage("set_speeds", [speed -speed -speed speed]) % opposite of left
            end

            if strcmp(key, "a")
                app.sendMessage("set_speeds", [-speed -speed speed speed]) % not sure
            end

            if strcmp(key, "d")
                app.sendMessage("set_speeds", [speed speed -speed -speed]) % opposite of a
            end


        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            app.priorKey = "";
            app.sendMessage("stop")

            
        end

        % Value changed function: StreamingSwitch
        function StreamingSwitchValueChanged(app, event)
            value = app.StreamingSwitch.Value;

            if strcmp(value, "On")
                app.sendMessage("is_streaming", true);
            else
                app.sendMessage("is_streaming", false);
            end
            
        end

        % Button pushed function: DriveuntilwallButton
        function DriveuntilwallButtonPushed(app, event)
            app.sendMessage("mode", ".drive_until_wall")
        end

        % Button pushed function: beepButton
        function beepButtonPushed(app, event)
            app.sendMessage("beep")
        end

        % Button pushed function: StartlinefollowingButton
        function StartlinefollowingButtonPushed(app, event)
            app.sendMessage("mode", ".start_line_following")
        end

        % Button pushed function: StoplinefollowingButton
        function StoplinefollowingButtonPushed(app, event)
            app.sendMessage("mode", ".stop_line_following")
        end

        % Button pushed function: DriveuntillineButton
        function DriveuntillineButtonPushed(app, event)
            app.sendMessage("mode", ".drive_until_line")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Value = 40;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Click in the area below to use the keyboard.';
            app.StreamingPanel = uipanel(app.UIFigure);
            app.StreamingPanel.Title = 'Streaming';
            app.StreamingSwitchLabel = uilabel(app.StreamingPanel);
            app.StreamingSwitchLabel.Text = 'Streaming';
            app.StreamingSwitch = uiswitch(app.StreamingPanel, 'slider');
            app.StreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingSwitchValueChanged, true);
            app.LeftLampLabel = uilabel(app.StreamingPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.StreamingPanel);
            app.MiddleLampLabel = uilabel(app.StreamingPanel);
            app.MiddleLampLabel.Text = 'Middle';
            app.MiddleLamp = uilamp(app.StreamingPanel);
            app.RightLampLabel = uilabel(app.StreamingPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.StreamingPanel);
            app.TimeTimestampLabel = uilabel(app.StreamingPanel);
            app.TimeTimestampLabel.Text = 'Time Timestamp';
            app.ModesPanel = uipanel(app.UIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveuntilwallButton = uibutton(app.ModesPanel, 'push');
            app.DriveuntilwallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntilwallButtonPushed, true);
            app.DriveuntilwallButton.Text = 'Drive until wall';
            app.StartlinefollowingButton = uibutton(app.ModesPanel, 'push');
            app.StartlinefollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StartlinefollowingButtonPushed, true);
            app.StartlinefollowingButton.Text = 'Start line following';
            app.StoplinefollowingButton = uibutton(app.ModesPanel, 'push');
            app.StoplinefollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StoplinefollowingButtonPushed, true);
            app.StoplinefollowingButton.Text = 'Stop line following';
            app.DriveuntillineButton = uibutton(app.ModesPanel, 'push');
            app.DriveuntillineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntillineButtonPushed, true);
            app.DriveuntillineButton.Text = 'Drive until line';
            app.MiscPanel = uipanel(app.UIFigure);
            app.MiscPanel.Title = 'Misc';
            app.beepButton = uibutton(app.MiscPanel, 'push');
            app.beepButton.ButtonPushedFcn = createCallbackFcn(app, @beepButtonPushed, true);
            app.beepButton.Text = 'beep';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hello

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end