classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RESETButton          matlab.ui.control.Button
        SetTimeDelaysPanel   matlab.ui.container.Panel
        RESETTime            matlab.ui.control.Button
        SETButton            matlab.ui.control.Button
        UITable              matlab.ui.control.Table
        ConnectPanel         matlab.ui.container.Panel
        Image4               matlab.ui.control.Image
        Lamp                 matlab.ui.control.Lamp
        Switch               matlab.ui.control.ToggleSwitch
        COMEditField         matlab.ui.control.NumericEditField
        COMEditFieldLabel    matlab.ui.control.Label
        DisplayPanel         matlab.ui.container.Panel
        gripperImage         matlab.ui.control.Image
        extenderImage        matlab.ui.control.Image
        bgImage              matlab.ui.control.Image
        ResponsePanel        matlab.ui.container.Panel
        Image4_2             matlab.ui.control.Image
        StatusBox            matlab.ui.control.Label
        GETSTATUSButton      matlab.ui.control.Button
        MovePanel            matlab.ui.container.Panel
        Label                matlab.ui.control.Label
        SPECIALMOVEButton    matlab.ui.control.Button
        MOVEPLATEButton      matlab.ui.control.Button
        Image3               matlab.ui.control.Image
        ENDEditField         matlab.ui.control.NumericEditField
        ENDEditFieldLabel    matlab.ui.control.Label
        STARTEditField       matlab.ui.control.NumericEditField
        STARTEditFieldLabel  matlab.ui.control.Label
        instructionPanel     matlab.ui.container.Panel
        Button_8             matlab.ui.control.Button
        Button_7             matlab.ui.control.Button
        Button_6             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        OPENCLOSELabel       matlab.ui.control.Label
        LEFTRIGHTLabel       matlab.ui.control.Label
        Button               matlab.ui.control.Button
        EXTENDRETRACTLabel   matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
    end

    
    properties (Access = private)
        isSim; %whether we are using the sim or the real plateloader
        plateLoader;
        plateLoaderSim;
        gripperImages;
        zPos;
        xPos;
        extenderPos;
        
    end
    
    methods (Access = private)
        
       function success = updateUI(app)
            if(app.isSim)
                [xpos, zpos, grip, plate] = app.plateLoaderSim.getProperties();
                app.gripperImage.Position(1) = app.xPos(xpos);
                if(zpos)
                    app.extenderImage.Visible = "on";
                    app.extenderImage.Position(1) = app.xPos(xpos);
                    app.gripperImage.Position(2) = app.zPos(2);
                else
                    app.extenderImage.Visible = "off";
                    app.gripperImage.Position(2) = app.zPos(1);
                end
                if(plate)
                    app.gripperImage.ImageSource = app.gripperImages(3);
                elseif (grip)
                    app.gripperImage.ImageSource = app.gripperImages(2);
                else 
                    app.gripperImage.ImageSource = app.gripperImages(1);
                end
            
                app.StatusBox.Text = app.plateLoaderSim.getStatus();
            else
                [xpos, zpos, grip, plate] = app.plateLoader.getProperties();
                app.gripperImage.Position(1) = app.xPos(xpos);
                if(zpos)
                    app.extenderImage.Visible = "on";
                    app.extenderImage.Position(1) = app.xPos(xpos);
                    app.gripperImage.Position(2) = app.zPos(2);
                else
                    app.extenderImage.Visible = "off";
                    app.gripperImage.Position(2) = app.zPos(1);
                end
                if(~plate && ~grip)
                    app.gripperImage.ImageSource = app.gripperImages(1);
                elseif (grip && ~plate)
                    app.gripperImage.ImageSource = app.gripperImages(2);
                else 
                    app.gripperImage.ImageSource = app.gripperImages(3);
                end
            
                app.StatusBox.Text = app.plateLoader.getStatus();
            end
            success = 1;

       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; 
            app.Switch.Value
            app.UITable.Data = [60 20 30
                                0 30 30
                                30 0 30
                                30 30 0
                                30 20 60];
            app.isSim = true;
            app.plateLoaderSim = PlateLoaderSim();
            app.plateLoaderSim.reset();
            app.xPos = [25, 97, 171, 240, 311];
            app.zPos = [170, 80];
            app.gripperImages = ["./gripper_open_no_plate.jpg","./gripper_closed_no_plate.jpg" ,"./gripper_with_plate.jpg"];
            app.updateUI();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    fprintf("rightarrow\n");
                    if(app.isSim)
                        [xPos, ~, ~, ~] = app.plateLoaderSim.getProperties();
                        app.plateLoaderSim.x(xPos + 1);
                    else
                        [xPos, ~, ~, ~] = app.plateLoader.getProperties();
                        app.plateLoader.x(xPos + 1);
                    end
                case 'leftarrow'
                    fprintf("leftarrow\n");
                    if(app.isSim)
                        [xPos, ~, ~, ~] = app.plateLoaderSim.getProperties();
                        app.plateLoaderSim.x(xPos - 1);
                    else
                        [xPos, ~, ~, ~] = app.plateLoader.getProperties();
                        app.plateLoader.x(xPos - 1);
                    end
                case 'downarrow'
                    fprintf("downarrow\n");
                    if(app.isSim)
                        app.plateLoaderSim.extend();
                    else
                        app.plateLoader.extend();
                    end
                case 'uparrow'
                    fprintf("uparrow\n");
                    if(app.isSim)
                        app.plateLoaderSim.retract();
                    else
                        app.plateLoader.retract();
                    end
                case 'space'
                    fprintf("space\n");
                    if(app.isSim)
                        [~, ~, grip, ~] = app.plateLoaderSim.getProperties();
                        if(grip)
                            app.plateLoaderSim.open();
                        else
                            app.plateLoaderSim.close();
                        end
                    else
                        [~, ~, grip, ~] = app.plateLoader.getProperties();
                        if(grip)
                            app.plateLoader.open();
                        else
                            app.plateLoader.close();
                        end
                    end
            end
            app.updateUI();
        end

        % Button pushed function: MOVEPLATEButton
        function MOVEPLATEButtonPushed(app, event)
            if(app.isSim)
                app.plateLoaderSim.movePlate(app.STARTEditField.Value, app.ENDEditField.Value);
            else
                app.plateLoader.movePlate(app.STARTEditField.Value, app.ENDEditField.Value);
            end
            app.updateUI();
        end

        % Button pushed function: RESETTime
        function RESETTimePushed(app, event)
            app.UITable.Data = [60 20 30
                0 30 30
                30 0 30
                30 30 0
                30 20 60];
            zero = [0
                0
                0
                0
                0];
            matrix = [zero, app.UITable.Data, zero];
            if(app.isSim)
                app.plateLoaderSim.setTimeValues(matrix);
            else
                app.plateLoader.setTimeValues(matrix);
            end
            app.updateUI();
        end

        % Button pushed function: SETButton
        function SETButtonPushed(app, event)
            zero = [0
                0
                0
                0
                0];
            matrix = [zero, app.UITable.Data, zero];
            if(app.isSim)
                app.plateLoaderSim.setTimeValues(matrix);
            else
                app.plateLoader.setTimeValues(matrix);
            end
            app.updateUI();
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            if(app.isSim)
                app.plateLoaderSim.reset();
            else
                app.plateLoader.reset();
            end
            app.updateUI();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(value == 1)
                if(isempty(app.plateLoader))
                    app.plateLoader = PlateLoader(app.COMEditField.Value);
                end
                app.Lamp.Color = [0,1,0];
                app.isSim = false;
                app.updateUI();
            else
                app.Lamp.Color = [1,0,0];
                app.plateLoader.shutdown();
                app.isSim = true;
                app.plateLoaderSim.reset();
                app.updateUI();
            end
        end

        % Button pushed function: SPECIALMOVEButton
        function SPECIALMOVEButtonPushed(app, event)
            if(app.isSim)
                app.plateLoaderSim.specialMove();
            else 
                app.plateLoader.specialMove();
            end
            app.updateUI();
        end

        % Button pushed function: GETSTATUSButton
        function GETSTATUSButtonPushed(app, event)
            if(app.isSim)
                app.StatusBox.Text = app.plateLoaderSim.getStatus();
            else
                app.StatusBox.Text = app.plateLoader.getStatus();
            end
            app.updateUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.instructionPanel = uipanel(app.UIFigure);
            app.instructionPanel.Title = 'Instructions';
            app.EXTENDRETRACTLabel = uilabel(app.instructionPanel);
            app.EXTENDRETRACTLabel.Text = 'EXTEND / RETRACT';
            app.Button = uibutton(app.instructionPanel, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'upArrow.png');
            app.Button.Text = '';
            app.LEFTRIGHTLabel = uilabel(app.instructionPanel);
            app.LEFTRIGHTLabel.Text = 'LEFT / RIGHT';
            app.OPENCLOSELabel = uilabel(app.instructionPanel);
            app.OPENCLOSELabel.Text = 'OPEN / CLOSE';
            app.Button_5 = uibutton(app.instructionPanel, 'push');
            app.Button_5.Icon = fullfile(pathToMLAPP, 'spacebar.png');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.instructionPanel, 'push');
            app.Button_6.Icon = fullfile(pathToMLAPP, 'downArrow.png');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.instructionPanel, 'push');
            app.Button_7.Icon = fullfile(pathToMLAPP, 'leftArrow.png');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.instructionPanel, 'push');
            app.Button_8.Icon = fullfile(pathToMLAPP, 'rightArrow.png');
            app.Button_8.Text = '';
            app.MovePanel = uipanel(app.UIFigure);
            app.MovePanel.Title = 'Move';
            app.STARTEditFieldLabel = uilabel(app.MovePanel);
            app.STARTEditFieldLabel.Text = 'START';
            app.STARTEditField = uieditfield(app.MovePanel, 'numeric');
            app.STARTEditField.Limits = [1 5];
            app.STARTEditField.Value = 1;
            app.ENDEditFieldLabel = uilabel(app.MovePanel);
            app.ENDEditFieldLabel.Text = 'END';
            app.ENDEditField = uieditfield(app.MovePanel, 'numeric');
            app.ENDEditField.Limits = [1 5];
            app.ENDEditField.Value = 1;
            app.Image3 = uiimage(app.MovePanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'arrowGray.png');
            app.MOVEPLATEButton = uibutton(app.MovePanel, 'push');
            app.MOVEPLATEButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEPLATEButtonPushed, true);
            app.MOVEPLATEButton.Text = 'MOVE PLATE';
            app.SPECIALMOVEButton = uibutton(app.MovePanel, 'push');
            app.SPECIALMOVEButton.ButtonPushedFcn = createCallbackFcn(app, @SPECIALMOVEButtonPushed, true);
            app.SPECIALMOVEButton.Text = 'SPECIAL MOVE';
            app.Label = uilabel(app.MovePanel);
            app.Label.Text = '_____________________________';
            app.ResponsePanel = uipanel(app.UIFigure);
            app.ResponsePanel.Title = 'Response';
            app.GETSTATUSButton = uibutton(app.ResponsePanel, 'push');
            app.GETSTATUSButton.ButtonPushedFcn = createCallbackFcn(app, @GETSTATUSButtonPushed, true);
            app.GETSTATUSButton.Text = 'GET STATUS';
            app.StatusBox = uilabel(app.ResponsePanel);
            app.StatusBox.Text = 'asdasd';
            app.Image4_2 = uiimage(app.ResponsePanel);
            app.Image4_2.ImageSource = 'leprechaunHappy.png';
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.bgImage = uiimage(app.DisplayPanel);
            app.bgImage.ImageSource = fullfile(pathToMLAPP, 'robot_background.jpg');
            app.extenderImage = uiimage(app.DisplayPanel);
            app.extenderImage.ImageSource = fullfile(pathToMLAPP, 'extended_bars.jpg');
            app.gripperImage = uiimage(app.DisplayPanel);
            app.gripperImage.ImageSource = fullfile(pathToMLAPP, 'gripper_open_no_plate.jpg');
            app.ConnectPanel = uipanel(app.UIFigure);
            app.ConnectPanel.Title = 'Connect';
            app.COMEditFieldLabel = uilabel(app.ConnectPanel);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.ConnectPanel, 'numeric');
            app.COMEditField.Limits = [0 Inf];
            app.Switch = uiswitch(app.ConnectPanel, 'toggle');
            app.Switch.Items = {'', ''};
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.Lamp = uilamp(app.ConnectPanel);
            app.Image4 = uiimage(app.ConnectPanel);
            app.Image4.ImageSource = 'leprechaunHappy.png';
            app.SetTimeDelaysPanel = uipanel(app.UIFigure);
            app.SetTimeDelaysPanel.Title = 'Set Time Delays';
            app.UITable = uitable(app.SetTimeDelaysPanel);
            app.UITable.ColumnName = {'2'; '3'; '4'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'};
            app.UITable.ColumnEditable = true;
            app.SETButton = uibutton(app.SetTimeDelaysPanel, 'push');
            app.SETButton.ButtonPushedFcn = createCallbackFcn(app, @SETButtonPushed, true);
            app.SETButton.Text = 'SET';
            app.RESETTime = uibutton(app.SetTimeDelaysPanel, 'push');
            app.RESETTime.ButtonPushedFcn = createCallbackFcn(app, @RESETTimePushed, true);
            app.RESETTime.Text = 'RESET';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end