classdef uiforserver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RESETButton          matlab.ui.control.Button
        ConnectPanel         matlab.ui.container.Panel
        Image4               matlab.ui.control.Image
        Lamp                 matlab.ui.control.Lamp
        Switch               matlab.ui.control.ToggleSwitch
        COMEditField         matlab.ui.control.NumericEditField
        COMEditFieldLabel    matlab.ui.control.Label
        DisplayPanel         matlab.ui.container.Panel
        gripperImage         matlab.ui.control.Image
        extenderImage        matlab.ui.control.Image
        bgImage              matlab.ui.control.Image
        ResponsePanel        matlab.ui.container.Panel
        Image4_2             matlab.ui.control.Image
        StatusBox            matlab.ui.control.Label
        GETSTATUSButton      matlab.ui.control.Button
        MovePanel            matlab.ui.container.Panel
        MOVEPLATEButton      matlab.ui.control.Button
        Image3               matlab.ui.control.Image
        ENDEditField         matlab.ui.control.NumericEditField
        ENDEditFieldLabel    matlab.ui.control.Label
        STARTEditField       matlab.ui.control.NumericEditField
        STARTEditFieldLabel  matlab.ui.control.Label
        instructionPanel     matlab.ui.container.Panel
        Button_8             matlab.ui.control.Button
        Button_7             matlab.ui.control.Button
        Button_6             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        OPENCLOSELabel       matlab.ui.control.Label
        LEFTRIGHTLabel       matlab.ui.control.Label
        Button               matlab.ui.control.Button
        EXTENDRETRACTLabel   matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
    end

    
    properties (Access = private)
        gripperImages;
        zPos;
        xPos;
        extenderPos;
        uri;
        r;
    end
    
    methods (Access = private)
        
       function success = updateUI(app)
            [xpos, zpos, grip, plate] = app.getProperties();
            app.gripperImage.Position(1) = app.xPos(xpos);
            if(zpos)
                app.extenderImage.Visible = "on";
                app.extenderImage.Position(1) = app.xPos(xpos);
                app.gripperImage.Position(2) = app.zPos(2);
            else
                app.extenderImage.Visible = "off";
                app.gripperImage.Position(2) = app.zPos(1);
            end
            if(~plate && ~grip)
                app.gripperImage.ImageSource = app.gripperImages(1);
            elseif (grip && ~plate)
                app.gripperImage.ImageSource = app.gripperImages(2);
            else 
                app.gripperImage.ImageSource = app.gripperImages(3);
            end
        
            app.StatusBox.Text = app.sendCommand("STATUS");
            success = 1;
       end
       
       function [x, extend, open, plate] = getProperties(app)
            resp = app.sendCommand("STATUS");
            
            tokens = resp.replace(',', '').split(" ")
            x = tokens(3);
            extend = tokens(5);
            open = tokens(7);
            if open.startsWith("CLOSED")
                plate = tokens(8);
            else
                plate = "NOPLATE";
            end
       end

       function response = sendCommand(app, command)
           import matlab.net.*
           import matlab.net.http.*
           app.r = RequestMessage;
           comm = app.uri + command;
           resp = send(app.r, URI(comm));
           response = resp.Body.Data;
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; 
            app.Switch.Value
            app.xPos = [25, 97, 171, 240, 311];
            app.zPos = [170, 80];
            app.gripperImages = ["./gripper_open_no_plate.jpg","./gripper_closed_no_plate.jpg" ,"./gripper_with_plate.jpg"];
            app.uri = "http://137.112.214.249:8080/api/";
            app.updateUI();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                        [xP, ~, ~, ~] = app.getProperties();
                        app.sendCommand("X-AXIS/" + (xP+1));
                case 'leftarrow'
                        [xP, ~, ~, ~] = app.getProperties();
                        app.sendCommand("X-AXIS/" + (xP-1));
                case 'downarrow'
                        app.sendCommand("Z-AXIS/EXTEND");
                case 'uparrow'
                        app.sendCommand("Z-AXIS/RETRACT");
                case 'space'
                        [~, ~, grip, ~] = app.getProperties();
                        if(grip)
                            app.sendCommand("GRIPPER/OPEN");
                        else
                            app.sendCommand("GRIPPER/CLOSE");
                        end
            end
            app.updateUI();
        end

        % Button pushed function: MOVEPLATEButton
        function MOVEPLATEButtonPushed(app, event)
            app.sendCommand("MOVE/" + app.STARTEditField.Value + "/" + app.ENDEditField.Value);    
            app.updateUI();
        end

        % Callback function
        function RESETTimePushed(app, event)
           
        end

        % Callback function
        function SETButtonPushed(app, event)
         
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.sendCommand("RESET");
            app.updateUI();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)

        end

        % Button pushed function: GETSTATUSButton
        function GETSTATUSButtonPushed(app, event)
            app.sendCommand("STATUS");
            app.updateUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.instructionPanel = uipanel(app.UIFigure);
            app.instructionPanel.Title = 'Instructions';
            app.EXTENDRETRACTLabel = uilabel(app.instructionPanel);
            app.EXTENDRETRACTLabel.Text = 'EXTEND / RETRACT';
            app.Button = uibutton(app.instructionPanel, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'upArrow.png');
            app.Button.Text = '';
            app.LEFTRIGHTLabel = uilabel(app.instructionPanel);
            app.LEFTRIGHTLabel.Text = 'LEFT / RIGHT';
            app.OPENCLOSELabel = uilabel(app.instructionPanel);
            app.OPENCLOSELabel.Text = 'OPEN / CLOSE';
            app.Button_5 = uibutton(app.instructionPanel, 'push');
            app.Button_5.Icon = fullfile(pathToMLAPP, 'spacebar.png');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.instructionPanel, 'push');
            app.Button_6.Icon = fullfile(pathToMLAPP, 'downArrow.png');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.instructionPanel, 'push');
            app.Button_7.Icon = fullfile(pathToMLAPP, 'leftArrow.png');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.instructionPanel, 'push');
            app.Button_8.Icon = fullfile(pathToMLAPP, 'rightArrow.png');
            app.Button_8.Text = '';
            app.MovePanel = uipanel(app.UIFigure);
            app.MovePanel.Title = 'Move';
            app.STARTEditFieldLabel = uilabel(app.MovePanel);
            app.STARTEditFieldLabel.Text = 'START';
            app.STARTEditField = uieditfield(app.MovePanel, 'numeric');
            app.STARTEditField.Limits = [1 5];
            app.STARTEditField.Value = 1;
            app.ENDEditFieldLabel = uilabel(app.MovePanel);
            app.ENDEditFieldLabel.Text = 'END';
            app.ENDEditField = uieditfield(app.MovePanel, 'numeric');
            app.ENDEditField.Limits = [1 5];
            app.ENDEditField.Value = 1;
            app.Image3 = uiimage(app.MovePanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'arrowGray.png');
            app.MOVEPLATEButton = uibutton(app.MovePanel, 'push');
            app.MOVEPLATEButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEPLATEButtonPushed, true);
            app.MOVEPLATEButton.Text = 'MOVE PLATE';
            app.ResponsePanel = uipanel(app.UIFigure);
            app.ResponsePanel.Title = 'Response';
            app.GETSTATUSButton = uibutton(app.ResponsePanel, 'push');
            app.GETSTATUSButton.ButtonPushedFcn = createCallbackFcn(app, @GETSTATUSButtonPushed, true);
            app.GETSTATUSButton.Text = 'GET STATUS';
            app.StatusBox = uilabel(app.ResponsePanel);
            app.StatusBox.Text = 'asdasd';
            app.Image4_2 = uiimage(app.ResponsePanel);
            app.Image4_2.ImageSource = 'leprechaunHappy.png';
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.bgImage = uiimage(app.DisplayPanel);
            app.bgImage.ImageSource = fullfile(pathToMLAPP, 'robot_background.jpg');
            app.extenderImage = uiimage(app.DisplayPanel);
            app.extenderImage.ImageSource = fullfile(pathToMLAPP, 'extended_bars.jpg');
            app.gripperImage = uiimage(app.DisplayPanel);
            app.gripperImage.ImageSource = fullfile(pathToMLAPP, 'gripper_open_no_plate.jpg');
            app.ConnectPanel = uipanel(app.UIFigure);
            app.ConnectPanel.Title = 'Connect';
            app.COMEditFieldLabel = uilabel(app.ConnectPanel);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.ConnectPanel, 'numeric');
            app.COMEditField.Limits = [0 Inf];
            app.Switch = uiswitch(app.ConnectPanel, 'toggle');
            app.Switch.Items = {'', ''};
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.Lamp = uilamp(app.ConnectPanel);
            app.Image4 = uiimage(app.ConnectPanel);
            app.Image4.ImageSource = 'leprechaunHappy.png';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uiforserver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end