classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LEDOnOffUIFigure  matlab.ui.Figure
        LEDOffButton_2    matlab.ui.control.Button
        LEDOnButton_2     matlab.ui.control.Button
        LEDOnOffLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        SerialObj
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.SerialObj = serialport("/dev/COM4", 19200, "TimeOut", 15);

        end

        % Button pushed function: LEDOnButton_2
        function LEDOnButton_2Pushed(app, event)
            fprintf("You pressed the ON button.\n");
            
            flush(app.SerialObj)
            writeline(app.SerialObj, "LED ON")
            response = readline(app.SerialObj);
            
        end

        % Button pushed function: LEDOffButton_2
        function LEDOffButton_2Pushed(app, event)
            fprintf("You pressed the OFF button.\n");

            flush(app.SerialObj)
            writeline(app.SerialObj, "LED OFF")
            response = readline(app.SerialObj);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LEDOnOffUIFigure = uifigure('Visible', 'off');
            app.LEDOnOffUIFigure.Name = 'LED On / Off';
            app.LEDOnOffLabel = uilabel(app.LEDOnOffUIFigure);
            app.LEDOnOffLabel.Text = 'LED On / Off';
            app.LEDOnButton_2 = uibutton(app.LEDOnOffUIFigure, 'push');
            app.LEDOnButton_2.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButton_2Pushed, true);
            app.LEDOnButton_2.Text = 'LED On';
            app.LEDOffButton_2 = uibutton(app.LEDOnOffUIFigure, 'push');
            app.LEDOffButton_2.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButton_2Pushed, true);
            app.LEDOffButton_2.Text = 'LED Off';
            % Show the figure after all components are created
            app.LEDOnOffUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LEDOnOffUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LEDOnOffUIFigure)
        end
    end
end