classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        Label3                 matlab.ui.control.Label
        Label2                 matlab.ui.control.Label
        Label1                 matlab.ui.control.Label
        GoTo3Button            matlab.ui.control.Button
        Set3Button             matlab.ui.control.Button
        GoTo2Button            matlab.ui.control.Button
        Set2Button             matlab.ui.control.Button
        GoTo1Button            matlab.ui.control.Button
        Set1Button             matlab.ui.control.Button
        NumericValuesPanel     matlab.ui.container.Panel
        HomeButton             matlab.ui.control.Button
        GOButton               matlab.ui.control.Button
        J3ValueEditField       matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel  matlab.ui.control.Label
        J2ValueEditField       matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel  matlab.ui.control.Label
        J1ValueEditField       matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel  matlab.ui.control.Label
        JointSlidersPanel      matlab.ui.container.Panel
        Joint3Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        Joint2Slider           matlab.ui.control.Slider
        Joint2SliderLabel      matlab.ui.control.Label
        Joint1Slider           matlab.ui.control.Slider
        Joint1SliderLabel      matlab.ui.control.Label
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        jointAngles = [0 0 0];

        savedAngles1 = [0 0 0];
        savedAngles2 = [0 0 0];
        savedAngles3 = [0 0 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Begin: Code that can go into your GUI's StartupFcn.
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-160 250];
            app.axes_arm.YLim = [-250 250];
            app.axes_arm.ZLim = [-22 22];

            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
            % End: Code that can go into your GUI's opening function.

            app.updateArm();

        end

        function updateArm(app)

            % Create the A matrices for the arm.
            A1 = create_A_matrix(64, 0, 0, app.jointAngles(1));
            A2 = create_A_matrix(0, 0, -90, app.jointAngles(2));
            A3 = create_A_matrix(0, 140, 0, app.jointAngles(3));

            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.

            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;

            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;

            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');

        end

        % Button down function: axes_arm
        function axes_armButtonDown(app, event)
            
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            app.jointAngles(1) = app.J1ValueEditField.Value;
            app.jointAngles(2) = app.J2ValueEditField.Value;
            app.jointAngles(3) = app.J3ValueEditField.Value;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm();
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.jointAngles(1) = 0;
            app.jointAngles(2) = 0;
            app.jointAngles(3) = 0;

            app.J1ValueEditField.Value = 0;
            app.J2ValueEditField.Value = 0;
            app.J3ValueEditField.Value = 0;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm();
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;

            % Also update the numeric value text field
            app.J1ValueEditField.Value = app.jointAngles(1);
            app.updateArm()
            
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;

            % Also update the numeric value text field
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.updateArm()
            
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;

            % Also update the numeric value text field
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.updateArm()
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.Label1.Text = num2str(app.jointAngles(1)) + "   " + num2str(app.jointAngles(2)) + "   " + num2str(app.jointAngles(3));
            app.savedAngles1 = [app.jointAngles(1) app.jointAngles(2) app.jointAngles(3)];
        end

        % Button pushed function: GoTo1Button
        function GoTo1ButtonPushed(app, event)
            app.jointAngles(1) = app.savedAngles1(1);
            app.jointAngles(2) = app.savedAngles1(2);
            app.jointAngles(3) = app.savedAngles1(3);

            app.J1ValueEditField.Value = app.savedAngles1(1);
            app.J2ValueEditField.Value = app.savedAngles1(2);
            app.J3ValueEditField.Value = app.savedAngles1(3);

            app.Joint1Slider.Value = app.savedAngles1(1);
            app.Joint2Slider.Value = app.savedAngles1(2);
            app.Joint3Slider.Value = app.savedAngles1(3);

            app.updateArm();
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.Label2.Text = num2str(app.jointAngles(1)) + "   " + num2str(app.jointAngles(2)) + "   " + num2str(app.jointAngles(3));
            app.savedAngles2 = [app.jointAngles(1) app.jointAngles(2) app.jointAngles(3)];
        end

        % Button pushed function: GoTo2Button
        function GoTo2ButtonPushed(app, event)
            app.jointAngles(1) = app.savedAngles2(1);
            app.jointAngles(2) = app.savedAngles2(2);
            app.jointAngles(3) = app.savedAngles2(3);

            app.J1ValueEditField.Value = app.savedAngles2(1);
            app.J2ValueEditField.Value = app.savedAngles2(2);
            app.J3ValueEditField.Value = app.savedAngles2(3);

            app.Joint1Slider.Value = app.savedAngles2(1);
            app.Joint2Slider.Value = app.savedAngles2(2);
            app.Joint3Slider.Value = app.savedAngles2(3);

            app.updateArm();
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.Label3.Text = num2str(app.jointAngles(1)) + "   " + num2str(app.jointAngles(2)) + "   " + num2str(app.jointAngles(3));
            app.savedAngles3 = [app.jointAngles(1) app.jointAngles(2) app.jointAngles(3)];
        end

        % Button pushed function: GoTo3Button
        function GoTo3ButtonPushed(app, event)
            app.jointAngles(1) = app.savedAngles3(1);
            app.jointAngles(2) = app.savedAngles3(2);
            app.jointAngles(3) = app.savedAngles3(3);

            app.J1ValueEditField.Value = app.savedAngles3(1);
            app.J2ValueEditField.Value = app.savedAngles3(2);
            app.J3ValueEditField.Value = app.savedAngles3(3);

            app.Joint1Slider.Value = app.savedAngles3(1);
            app.Joint2Slider.Value = app.savedAngles3(2);
            app.Joint3Slider.Value = app.savedAngles3(3);

            app.updateArm();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.axes_arm.ButtonDownFcn = createCallbackFcn(app, @axes_armButtonDown, true);
            app.JointSlidersPanel = uipanel(app.UIFigure);
            app.JointSlidersPanel.Title = 'Joint Sliders';
            app.Joint1SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.JointSlidersPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.JointSlidersPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.JointSlidersPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.NumericValuesPanel = uipanel(app.UIFigure);
            app.NumericValuesPanel.Title = 'Numeric Values';
            app.J1ValueEditFieldLabel = uilabel(app.NumericValuesPanel);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.NumericValuesPanel, 'numeric');
            app.J2ValueEditFieldLabel = uilabel(app.NumericValuesPanel);
            app.J2ValueEditFieldLabel.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.NumericValuesPanel, 'numeric');
            app.J3ValueEditFieldLabel = uilabel(app.NumericValuesPanel);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.NumericValuesPanel, 'numeric');
            app.GOButton = uibutton(app.NumericValuesPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.HomeButton = uibutton(app.NumericValuesPanel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Set1Button = uibutton(app.Panel, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.GoTo1Button = uibutton(app.Panel, 'push');
            app.GoTo1Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo1ButtonPushed, true);
            app.GoTo1Button.Text = 'Go To 1';
            app.Set2Button = uibutton(app.Panel, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.GoTo2Button = uibutton(app.Panel, 'push');
            app.GoTo2Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo2ButtonPushed, true);
            app.GoTo2Button.Text = 'Go To 2';
            app.Set3Button = uibutton(app.Panel, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.GoTo3Button = uibutton(app.Panel, 'push');
            app.GoTo3Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo3ButtonPushed, true);
            app.GoTo3Button.Text = 'Go To 3';
            app.Label1 = uilabel(app.Panel);
            app.Label2 = uilabel(app.Panel);
            app.Label3 = uilabel(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end