classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        names        matlab.ui.control.Label
        cell9        matlab.ui.control.Button
        cell8        matlab.ui.control.Button
        cell7        matlab.ui.control.Button
        cell6        matlab.ui.control.Button
        cell5        matlab.ui.control.Button
        cell4        matlab.ui.control.Button
        cell3        matlab.ui.control.Button
        cell2        matlab.ui.control.Button
        cell1        matlab.ui.control.Button
        statusLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        board = strings(1, 9);
        currentPlayer = "X";
        gameOver = false;
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            if messageType == "reset"
                app.resetGame()
            end
            
        end
        
        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            messageStruct.payload = messagePayload;
            messageJson = jsonencode(messageStruct);
            
            write(app.mqClient, "ME435/tsurutn/to_pi", messageJson);
            fprintf("Sent: %s\n", messageJson);
        end

        function sendLeds(app)
            % led order is red yellow green
            % red = x turn
            % green = 0 turn
            % yellow = game over
            % can change color to wtv later 
            if app.gameOver
                led_values = [0 1 0];
            elseif app.currentPlayer == "X"
                led_values = [1 0 0];
            else
                led_values = [0 0 1];
            end
            app.sendMessage("leds", led_values);
        end

        function resetGame(app)
            app.board = strings(1, 9);
            app.currentPlayer = "X";
            app.gameOver = false;
            for k = 1:9
                app.(sprintf("cell%d", k)).Text = "";
            end
            app.statusLabel.Text = "X's turn";
            app.sendLeds();
        end

        function winner = checkWinner(app)
            winningLines = [1 2 3; 4 5 6; 7 8 9;   % rows
                            1 4 7; 2 5 8; 3 6 9;   % columsn
                            1 5 9; 3 5 7];         % diag
            winner = "";
            for k = 1:size(winningLines, 1)
                a = app.board(winningLines(k, 1));
                b = app.board(winningLines(k, 2));
                c = app.board(winningLines(k, 3));
                if a ~= "" && a == b && b == c
                    winner = a;
                    return
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("ME435/tsurutn/to_computer", "Callback", @app.myCallback);
            app.resetGame();
        end

        % Button pushed function: cell1, cell2, cell3, cell4, cell5, 
        % ...and 4 other components
        function cell1ButtonPushed(app, event)
            if app.gameOver
                return
            end

            idx = str2double(event.Source.Tag)

            % if square is alr taken
            if app.board(idx) ~= ""
                return
            end
            
            % set current player and txt color
            app.board(idx) = app.currentPlayer;
            event.Source.Text = app.currentPlayer;
            if app.currentPlayer == "X"
                event.Source.FontColor = [131 197 190]/255;
            else
                event.Source.FontColor = [255 221 210]/255;
            end

            winner = app.checkWinner();
            if winner ~= ""
                app.gameOver = true;
                app.statusLabel.Text = sprintf("%s wins! press reset", winner);
            elseif all(app.board ~= "")
                app.gameOver = true;
                app.statusLabel.Text = "draw. press reset NOW";
            else
                if app.currentPlayer == "X"
                   app.currentPlayer = "O";
                else
                    app.currentPlayer = "X";
                end
                app.statusLabel.Text = sprintf("%s's turn", app.currentPlayer);
            end

            app.sendLeds();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.statusLabel = uilabel(app.UIFigure);
            app.cell1 = uibutton(app.UIFigure, 'push');
            app.cell1.ButtonPushedFcn = createCallbackFcn(app, @cell1ButtonPushed, true);
            app.cell1.Tag = '1';
            app.cell1.Text = '';
            app.cell2 = uibutton(app.UIFigure, 'push');
            app.cell2.ButtonPushedFcn = createCallbackFcn(app, @cell1ButtonPushed, true);
            app.cell2.Tag = '2';
            app.cell2.Text = '';
            app.cell3 = uibutton(app.UIFigure, 'push');
            app.cell3.ButtonPushedFcn = createCallbackFcn(app, @cell1ButtonPushed, true);
            app.cell3.Tag = '3';
            app.cell3.Text = '';
            app.cell4 = uibutton(app.UIFigure, 'push');
            app.cell4.ButtonPushedFcn = createCallbackFcn(app, @cell1ButtonPushed, true);
            app.cell4.Tag = '4';
            app.cell4.Text = '';
            app.cell5 = uibutton(app.UIFigure, 'push');
            app.cell5.ButtonPushedFcn = createCallbackFcn(app, @cell1ButtonPushed, true);
            app.cell5.Tag = '5';
            app.cell5.Text = '';
            app.cell6 = uibutton(app.UIFigure, 'push');
            app.cell6.ButtonPushedFcn = createCallbackFcn(app, @cell1ButtonPushed, true);
            app.cell6.Tag = '6';
            app.cell6.Text = '';
            app.cell7 = uibutton(app.UIFigure, 'push');
            app.cell7.ButtonPushedFcn = createCallbackFcn(app, @cell1ButtonPushed, true);
            app.cell7.Tag = '7';
            app.cell7.Text = '';
            app.cell8 = uibutton(app.UIFigure, 'push');
            app.cell8.ButtonPushedFcn = createCallbackFcn(app, @cell1ButtonPushed, true);
            app.cell8.Tag = '8';
            app.cell8.Text = '';
            app.cell9 = uibutton(app.UIFigure, 'push');
            app.cell9.ButtonPushedFcn = createCallbackFcn(app, @cell1ButtonPushed, true);
            app.cell9.Tag = '9';
            app.cell9.Text = '';
            app.names = uilabel(app.UIFigure);
            app.names.Text = 'ME435 | Sandya Suresh and Niko Tsurutome';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end